classdef Interfata_Stabilire_Doza_Optima < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ModelPKPanel                    matlab.ui.container.Panel
        OrderDropDown                   matlab.ui.control.DropDown
        NrCompartimenteLabel            matlab.ui.control.Label
        CentralEditField                matlab.ui.control.NumericEditField
        CentralEditFieldLabel           matlab.ui.control.Label
        ParametriipentrudouacompartimentePanel  matlab.ui.container.Panel
        k21EditField                    matlab.ui.control.NumericEditField
        k21EditFieldLabel               matlab.ui.control.Label
        k12EditField                    matlab.ui.control.NumericEditField
        k12EditFieldLabel               matlab.ui.control.Label
        ParametriipentrutreicompartimentePanel  matlab.ui.container.Panel
        k31EditField                    matlab.ui.control.NumericEditField
        k31EditFieldLabel               matlab.ui.control.Label
        k13EditField                    matlab.ui.control.NumericEditField
        k13EditFieldLabel               matlab.ui.control.Label
        ModelPDPanel                    matlab.ui.container.Panel
        k2EditField                     matlab.ui.control.NumericEditField
        k2EditFieldLabel                matlab.ui.control.Label
        k1EditField                     matlab.ui.control.NumericEditField
        k1EditFieldLabel                matlab.ui.control.Label
        KmaxEditField                   matlab.ui.control.NumericEditField
        KmaxEditFieldLabel              matlab.ui.control.Label
        DatePacient                     matlab.ui.container.Panel
        SexDropDown                     matlab.ui.control.DropDown
        SexLabel                        matlab.ui.control.Label
        CrClEditField                   matlab.ui.control.NumericEditField
        CrClEditFieldLabel              matlab.ui.control.Label
        CreatininaSericaEditField       matlab.ui.control.NumericEditField
        CreatininaSericaEditFieldLabel  matlab.ui.control.Label
        VarstaEditField                 matlab.ui.control.NumericEditField
        VarstaEditFieldLabel            matlab.ui.control.Label
        GreutateEditField               matlab.ui.control.NumericEditField
        GreutateEditFieldLabel          matlab.ui.control.Label
        SimulareDozajPanel              matlab.ui.container.Panel
        TestaremetodededozareButton     matlab.ui.control.Button
        DozajPacientButton              matlab.ui.control.Button
        IncarcareSalvareDatePanel       matlab.ui.container.Panel
        IncarcareDateButton             matlab.ui.control.Button
        SalvareDateButton               matlab.ui.control.Button
        RezultateSimularePanel          matlab.ui.container.Panel
        BC500mgtidUIAxes                matlab.ui.control.UIAxes
        BC500mgbidUIAxes                matlab.ui.control.UIAxes
        BC250mgtidUIAxes                matlab.ui.control.UIAxes
        BC250mgbidUIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DozajPacientButton
        function DozajPacientButtonPushed(app, event)
   
        %Clear plot
        cla(app.BC250mgbidUIAxes);
        cla(app.BC250mgtidUIAxes);
        cla(app.BC500mgbidUIAxes);
        cla(app.BC500mgtidUIAxes);
        
        app.CrClEditField.Value = 0;

        app.CentralEditField.Enable ='off';
        app.CentralEditFieldLabel.Enable = 'off';
        app.k12EditField.Enable ='off';
        app.k12EditFieldLabel.Enable ='off';
        app.k21EditField.Enable ='off';
        app.k21EditFieldLabel.Enable ='off';
        app.k13EditField.Enable ='off';
        app.k13EditFieldLabel.Enable ='off';
        app.k31EditField.Enable ='off';
        app.k31EditFieldLabel.Enable ='off';
        app.KmaxEditField.Enable = 'off';
        app.KmaxEditFieldLabel.Enable = 'off';
        app.k1EditField.Enable = 'off';
        app.k1EditFieldLabel.Enable = 'off';
        app.k2EditField.Enable = 'off';
        app.k2EditFieldLabel.Enable = 'off';
        app.GreutateEditField.Enable = 'off';
        app.GreutateEditFieldLabel.Enable = 'off';
        app.VarstaEditField.Enable = 'off';
        app.VarstaEditFieldLabel.Enable = 'off';
        app.CreatininaSericaEditField.Enable = 'off';
        app.CreatininaSericaEditFieldLabel.Enable = 'off';
        app.DozajPacientButton.Enable = 'off';
        app.OrderDropDown.Enable = 'off';
        app.NrCompartimenteLabel.Enable = 'off';
        app.SexDropDown.Enable = 'off';
        app.SexLabel.Enable = 'off';
        app.TestaremetodededozareButton.Enable='off';
        app.SalvareDateButton.Enable ='off';
        app.IncarcareDateButton.Enable ='off';
              
        pause(0.01);
        
        %Incarcare date
        order = app.OrderDropDown.Value;
        
        
        switch order
            case '1'
                sbioloadproject('AntibacterialPKPD_OrdinZero.sbproj', 'm1') ;
            case '2'
                sbioloadproject('AntibacterialPKPD.sbproj', 'm1') ;
            case '3'
                sbioloadproject('AntibacterialPKPD_OrdinDoi.sbproj', 'm1') ;
        end
        % Doze
        
        doseNames = {'250 mg bid', '250 mg tid', '500 mg bid', '500 mg tid'};
        
        for iDoseGrp = 1:length(doseNames)
        
            doseRegimens(iDoseGrp) = sbioselect(m1, 'Name', doseNames{iDoseGrp}) ;
        
        end

        nPatients    = 1        ; % Numar pacienti in fiecare grupa de dozaj
        nDoseGrps    = 4            ; % Numar de grupe de dozaj


        k12_val = app.k12EditField.Value;
        k21_val = app.k21EditField.Value;
        k13_val = app.k13EditField.Value;
        k31_val = app.k31EditField.Value;
        k1_val = app.k1EditField.Value;
        k2_val = app.k2EditField.Value;
        Kmax_val = app.KmaxEditField.Value;
        Wt_val = app.GreutateEditField.Value;
        Age_val = app.VarstaEditField.Value;
        Scr_val = app.CreatininaSericaEditField.Value;
        Central_val = app.CentralEditField.Value;


        % Date pacient
        rng('default');
        Wt        = [Wt_val,Wt_val,Wt_val,Wt_val]  ; % kg
        Age       = [Age_val,Age_val,Age_val,Age_val]   ; % ani
        Scr       = [Scr_val,Scr_val,Scr_val,Scr_val]   ; % ml/minut 
        
        %Ecuația Cockcroft-Gault
        CrCL            = (140 - Age).*Wt./(Scr*72);  % ml/minut

        if(app.SexDropDown.Value == "Feminin")
         
            CrCL = CrCL * 0.85; % ml/minut
        
        end
        
        %Afisare CrCL
         app.CrClEditField.Value = CrCL(1);


        %Date model PK/PD
        Central = [Central_val, Central_val, Central_val, Central_val]; % litru
        k12     = [k12_val,k12_val,k12_val,k12_val]        ; % 1/ora
        k21     = [k21_val,k21_val,k21_val,k21_val]         ; % 1/ora
        k13     = [k13_val,k13_val,k13_val,k13_val]         ; % 1/ora
        k31     = [k31_val,k31_val,k31_val,k31_val]         ; % 1/ora
        
        CL      = [1.07*CrCL + 45.6, 1.07*CrCL + 45.6, 1.07*CrCL + 45.6, 1.07*CrCL + 45.6]; % ml/minut

        k1   = [k1_val,k1_val,k1_val,k1_val]     ; % 1/ora
        k2   = [k2_val,k2_val,k2_val,k2_val]     ; % 1/ora
        Kmax = [Kmax_val,Kmax_val,Kmax_val,Kmax_val]  ; % units: 1/ora
        
        % Distribuția discretă a valorilor MIC (concentrația minimă inhibitorie)
        micValue  = [0.0625, 0.125, 0.25, 0.5 , 1 , 2 , 4 , 8 , 16 , 32 ] ;
        micFreq   = [   5  ,    8 ,  9  , 14  , 7 , 8 , 9 , 5 , 2  , 4  ] ;
        MIC = nan(nPatients, nDoseGrps) ; % preallocate
        for iDoseGrp = 1:nDoseGrps
            MIC(:, iDoseGrp) = randsample(micValue , nPatients, true , micFreq);
        end
        
        KC50 = [exp(-1.91 + 0.898*log(MIC)),exp(-1.91 + 0.898*log(MIC)),exp(-1.91 + 0.898*log(MIC)),exp(-1.91 + 0.898*log(MIC))] ; % units: microgram
        
        % Simulare Setup
        switch order
            case '1'
                params      = {'Central', 'CL', 'k1', 'k2', 'Kmax', 'KC50'};
            case '2'
                params      = {'Central', 'k12', 'k21', 'CL', 'k1', 'k2', 'Kmax', 'KC50'};
            case '3'
                params      = {'Central', 'k12', 'k21', 'k13', 'k31', 'CL', 'k1', 'k2', 'Kmax', 'KC50'};
        end
        
        observables = {'[Bacterial Growth Model].Growing',...
        '[Bacterial Growth Model].Resting'};
        
        tempdose = sbiodose('dose');
        tempdose.Target = 'Central.Drug';
        tempdose.AmountUnits = 'milligram';
        tempdose.TimeUnits = 'hour';
        tempdose.DurationParameterName = 'TDose';
        
        simfunc  = createSimFunction(m1,params,observables,tempdose,'UseParallel',true);
        
        phi = cell(1,nDoseGrps);
        
        
        for i = 1:nDoseGrps
        
            switch order
                case '1'
                    phi{i} = [Central(:,i), ...
                    CL(:,i), k1(:,i), k2(:,i), ...
                    Kmax(:,i), KC50(:,i)];
                case '2'
                    phi{i} = [Central(:,i),k12(:,i),k21(:,i), ...
                    CL(:,i), k1(:,i), k2(:,i), ...
                    Kmax(:,i), KC50(:,i)];
                case '3'
                    phi{i} = [Central(:,i),k12(:,i),k21(:,i),k13(:,i),k31(:,i), ...
                    CL(:,i), k1(:,i), k2(:,i), ...
                    Kmax(:,i), KC50(:,i)];
            end
        end
        
        % Cluster Computing
        
        if isempty(gcp)
            parpool;
        end
        
        tObs      = 0:24:336       ; % ora
        nTPoints  = length(tObs)   ; % numar puncte
        
        % Simulare Monte Carlo pentru pacienti
        cfu          = nan(nTPoints,nPatients);
        log10CFU     = cell(1,nDoseGrps) ;
        
        for i = 1:nDoseGrps
            disp(['Simulating group ', num2str(i),'...'])
        
            % Alegere doze
            doseTable = getTable(doseRegimens(i));
        
            % Simulare
            simdata = simfunc(phi{i},[],doseTable,tObs);
        
            % Suma numarului de bacterii în crestere si repaus
            for j = 1:nPatients
                cfu(:,j) = sum(simdata(j).Data,2);
            end
            % Salvare transformari in logaritmi pentru fiecare grupa de doza
            log10CFU{i} = log10(cfu);
        end
        
        % Salvare rezultate
        log10CFU_250bid = log10CFU{1} ;
        log10CFU_250tid = log10CFU{2} ;
        log10CFU_500bid = log10CFU{3} ;
        log10CFU_500tid = log10CFU{4} ;
        
        %Convertire complex to double
        
        log10CFU_250bid = real(log10CFU_250bid);
        log10CFU_250tid = real(log10CFU_250tid);
        log10CFU_500bid = real(log10CFU_500bid);
        log10CFU_500tid = real(log10CFU_500tid);
        
        %Terminare Cluster Computing
        delete(gcp('nocreate'));

        % Afisare evolutie in timp ale numarului de bacterii
        n = numel(tObs);
        
        for i = 1:n
        

            plot(app.BC250mgbidUIAxes,tObs(1:i), log10CFU_250bid(1:i));
            plot(app.BC250mgtidUIAxes,tObs(1:i), log10CFU_250tid(1:i));
            plot(app.BC500mgbidUIAxes,tObs(1:i), log10CFU_500bid(1:i));
            plot(app.BC500mgtidUIAxes,tObs(1:i), log10CFU_500tid(1:i));

            pause(0.5);

        end
  
        app.CentralEditField.Enable ='on';
        app.CentralEditFieldLabel.Enable = 'on';
        app.KmaxEditField.Enable = 'on';
        app.KmaxEditFieldLabel.Enable = 'on';
        app.k1EditField.Enable = 'on';
        app.k1EditFieldLabel.Enable = 'on';
        app.k2EditField.Enable = 'on';
        app.k2EditFieldLabel.Enable = 'on';
        app.GreutateEditField.Enable = 'on';
        app.GreutateEditFieldLabel.Enable = 'on';
        app.VarstaEditField.Enable = 'on';
        app.VarstaEditFieldLabel.Enable = 'on';
        app.CreatininaSericaEditField.Enable = 'on';
        app.CreatininaSericaEditFieldLabel.Enable = 'on';
        app.DozajPacientButton.Enable = 'on';
        app.OrderDropDown.Enable = 'on';
        app.NrCompartimenteLabel.Enable = 'on';
        app.SexDropDown.Enable = 'on';
        app.SexLabel.Enable = 'on';
        app.TestaremetodededozareButton.Enable='on';
        app.SalvareDateButton.Enable ='on';
        app.IncarcareDateButton.Enable ='on';
        
        switch order
            case '2'
                app.k12EditField.Enable ='on';
                app.k12EditFieldLabel.Enable ='on';
                app.k21EditField.Enable ='on';
                app.k21EditFieldLabel.Enable ='on';
                app.k13EditField.Enable ='off';
                app.k13EditFieldLabel.Enable ='off';
                app.k31EditField.Enable ='off';
                app.k31EditFieldLabel.Enable ='off';
            case '3'
                app.k12EditField.Enable ='on';
                app.k12EditFieldLabel.Enable ='on';
                app.k21EditField.Enable ='on';
                app.k21EditFieldLabel.Enable ='on';
                app.k13EditField.Enable ='on';
                app.k13EditFieldLabel.Enable ='on';
                app.k31EditField.Enable ='on';
                app.k31EditFieldLabel.Enable ='on';
        end
        end

        % Value changed function: OrderDropDown
        function OrderDropDownValueChanged(app, event)
            order = app.OrderDropDown.Value;
            switch order
                case '1'
                    app.k12EditField.Enable ='off';
                    app.k12EditFieldLabel.Enable ='off';
                    app.k21EditField.Enable ='off';
                    app.k21EditFieldLabel.Enable ='off';
                    app.k13EditField.Enable ='off';
                    app.k13EditFieldLabel.Enable ='off';
                    app.k31EditField.Enable ='off';
                    app.k31EditFieldLabel.Enable ='off';
                case '2'
                    app.k12EditField.Enable ='on';
                    app.k12EditFieldLabel.Enable ='on';
                    app.k21EditField.Enable ='on';
                    app.k21EditFieldLabel.Enable ='on';
                    app.k13EditField.Enable ='off';
                    app.k13EditFieldLabel.Enable ='off';
                    app.k31EditField.Enable ='off';
                    app.k31EditFieldLabel.Enable ='off';
                case '3'
                    app.k12EditField.Enable ='on';
                    app.k12EditFieldLabel.Enable ='on';
                    app.k21EditField.Enable ='on';
                    app.k21EditFieldLabel.Enable ='on';
                    app.k13EditField.Enable ='on';
                    app.k13EditFieldLabel.Enable ='on';
                    app.k31EditField.Enable ='on';
                    app.k31EditFieldLabel.Enable ='on';
            end
        end

        % Button pushed function: TestaremetodededozareButton
        function TestaremetodededozareButtonPushed(app, event)

        app.CentralEditField.Enable ='off';
        app.CentralEditFieldLabel.Enable = 'off';
        app.k12EditField.Enable ='off';
        app.k12EditFieldLabel.Enable ='off';
        app.k21EditField.Enable ='off';
        app.k21EditFieldLabel.Enable ='off';
        app.k13EditField.Enable ='off';
        app.k13EditFieldLabel.Enable ='off';
        app.k31EditField.Enable ='off';
        app.k31EditFieldLabel.Enable ='off';
        app.KmaxEditField.Enable = 'off';
        app.KmaxEditFieldLabel.Enable = 'off';
        app.k1EditField.Enable = 'off';
        app.k1EditFieldLabel.Enable = 'off';
        app.k2EditField.Enable = 'off';
        app.k2EditFieldLabel.Enable = 'off';
        app.GreutateEditField.Enable = 'off';
        app.GreutateEditFieldLabel.Enable = 'off';
        app.VarstaEditField.Enable = 'off';
        app.VarstaEditFieldLabel.Enable = 'off';
        app.CreatininaSericaEditField.Enable = 'off';
        app.CreatininaSericaEditFieldLabel.Enable = 'off';
        app.DozajPacientButton.Enable = 'off';
        app.OrderDropDown.Enable = 'off';
        app.NrCompartimenteLabel.Enable = 'off';
        app.SexDropDown.Enable = 'off';
        app.SexLabel.Enable = 'off';
        app.TestaremetodededozareButton.Enable='off';
        app.SalvareDateButton.Enable ='off';
        app.IncarcareDateButton.Enable ='off';

        pause(0.01)


        % Incarcare Model
            order = app.OrderDropDown.Value;
            switch order
                case '1'
                    sbioloadproject('AntibacterialPKPD_OrdinZero.sbproj', 'm1') ;
                case '2'
                    sbioloadproject('AntibacterialPKPD.sbproj', 'm1') ;
                case '3'
                    sbioloadproject('AntibacterialPKPD_OrdinDoi.sbproj', 'm1') ;
            end
            % Doza

            doseNames = {'250 mg bid', '250 mg tid', '500 mg bid', '500 mg tid'};

            for iDoseGrp = 1:length(doseNames)

                doseRegimens(iDoseGrp) = sbioselect(m1, 'Name', doseNames{iDoseGrp}) ;

            end


            % Setup
            
           nPatients = 1000         ; % Numar pacienti in fiecare grupa de dozaj
           nDoseGrps = 4            ; % Numar de grupe de dozaj
            
            mu    = @(m,v) log(m^2/sqrt(v+m^2));
            sigma = @(m,v) sqrt(log(v/m^2+1));
            m     = @(typicalValue) typicalValue;
            v     = @(typicalValue,CV) typicalValue^2*CV^2;
            
            k12_val = app.k12EditField.Value;
            k21_val = app.k21EditField.Value;
            k13_val = app.k13EditField.Value;
            k31_val = app.k31EditField.Value;
            k1_val = app.k1EditField.Value;
            k2_val = app.k2EditField.Value;
            Kmax_val = app.KmaxEditField.Value;
            Central_val = app.CentralEditField.Value;


            % Date pacienti
            rng('default');
            Wt        = normrnd(51.6, 11.8, nPatients , nDoseGrps )   ; % kg
            Age       = normrnd(71.8, 11.9, nPatients , nDoseGrps )   ; % ani
            Scr_mu    =    mu(m(0.78), v(0.78,0.328));
            Scr_sigma = sigma(m(0.78), v(0.78,0.328));
            Scr       = lognrnd(Scr_mu, Scr_sigma, nPatients , nDoseGrps )   ; % ml/minut
            
            
            id        = 1:nPatients*nDoseGrps                            ;
            idFemale  = randsample(id , round(0.26*nDoseGrps*nPatients)) ; % 26% Femei
            
            %Ecuația Cockcroft-Gault 
            CrCL            = (140 - Age).*Wt./(Scr*72)                 ; % ml/minut
            CrCL(idFemale)  = CrCL(idFemale)*0.85                       ; % 0.85 pentru Femei
            
            %Date model PK/PD
            Central_mu    =    mu(m(Central_val), v(Central_val,0.20));
            Central_sigma = sigma(m(Central_val), v(Central_val,0.20));
            k12_mu        =    mu(m(k12_val), v(k12_val,0.20));
            k12_sigma     = sigma(m(k12_val), v(k12_val,0.20));
            k21_mu        =    mu(m(k21_val), v(k21_val, 0.2));
            k21_sigma     = sigma(m(k21_val), v(k21_val, 0.2));
            k13_mu        =    mu(m(k13_val), v(k13_val,0.20));
            k13_sigma     = sigma(m(k13_val), v(k13_val,0.20));
            k31_mu        =    mu(m(k31_val), v(k31_val, 0.2));
            k31_sigma     = sigma(m(k31_val), v(k31_val, 0.2));
            
            Central = lognrnd(Central_mu , Central_sigma, nPatients , nDoseGrps); % litru
            k12     = lognrnd(k12_mu, k12_sigma, nPatients , nDoseGrps)         ; % 1/ora
            k21     = lognrnd(k21_mu, k21_sigma, nPatients , nDoseGrps)         ; % 1/ora
            k13     = lognrnd(k13_mu, k13_sigma, nPatients , nDoseGrps)         ; % 1/ora
            k31     = lognrnd(k31_mu, k31_sigma, nPatients , nDoseGrps)         ; % 1/ora
            
            CL      = 1.07*CrCL + 45.6 + normrnd(0,22,nPatients,nDoseGrps); % ml/minut
            
            k1_mu      =    mu(m(k1_val), v(k1_val, 0.2));
            k1_sigma   = sigma(m(k1_val), v(k1_val, 0.2));
            k2_mu      =    mu(m(k2_val) , v(k2_val, 0.2));
            k2_sigma   = sigma(m(k2_val) , v(k2_val, 0.2));
            Kmax_mu    =    mu(m(Kmax_val)   , v(Kmax_val, 0.159));
            Kmax_sigma = sigma(m(Kmax_val)   , v(Kmax_val, 0.159));
            
            k1   = lognrnd(k1_mu, k1_sigma, nPatients , nDoseGrps)     ; % 1/ora
            k2   = lognrnd(k2_mu, k2_sigma, nPatients , nDoseGrps)     ; % 1/ora
            Kmax = lognrnd(Kmax_mu, Kmax_sigma, nPatients , nDoseGrps) ; % 1/ora
            
            % Distribuția discretă a valorilor MIC (concentrația minimă inhibitorie)
            micValue  = [0.0625, 0.125, 0.25, 0.5 , 1 , 2 , 4 , 8 , 16 , 32 ] ;
            micFreq   = [   5  ,    8 ,  9  , 14  , 7 , 8 , 9 , 5 , 2  , 4  ] ;
            
            MIC = nan(nPatients, nDoseGrps) ; % preallocate
            for iDoseGrp = 1:nDoseGrps
                MIC(:, iDoseGrp) = randsample(micValue , nPatients, true , micFreq);
            end
            
            KC50 = exp(-1.91 + 0.898*log(MIC) + 1.06*randn(nPatients , nDoseGrps)) ; % units: microgram
            
            % Simulare Setup 
            
            params      = {'Central', 'k12', 'k21', 'CL', 'k1', 'k2', 'Kmax', 'KC50'};
                        switch order
                            case '1'
                                params      = {'Central', 'CL', 'k1', 'k2', 'Kmax', 'KC50'};
                            case '2'
                                params      = {'Central', 'k12', 'k21', 'CL', 'k1', 'k2', 'Kmax', 'KC50'};
                            case '3'
                                params      = {'Central', 'k12', 'k21', 'k13', 'k31', 'CL', 'k1', 'k2', 'Kmax', 'KC50'};
                        end
            
            observables = {'[Bacterial Growth Model].Growing',...
                           '[Bacterial Growth Model].Resting'};
            
            tempdose = sbiodose('dose');
            tempdose.Target = 'Central.Drug';
            tempdose.AmountUnits = 'milligram';
            tempdose.TimeUnits = 'hour';
            tempdose.DurationParameterName = 'TDose';
            
            simfunc  = createSimFunction(m1,params,observables,tempdose,'UseParallel',true);
            
            phi = cell(1,nDoseGrps);
            
            
            for i = 1:nDoseGrps
            
                switch order
                            case '1'
                                phi{i} = [Central(:,i), ...
                                          CL(:,i), k1(:,i), k2(:,i), ...
                                          Kmax(:,i), KC50(:,i)];
                            case '2'
                                phi{i} = [Central(:,i),k12(:,i),k21(:,i), ...
                                          CL(:,i), k1(:,i), k2(:,i), ...
                                          Kmax(:,i), KC50(:,i)];
                            case '3'
                                phi{i} = [Central(:,i),k12(:,i),k21(:,i),k13(:,i),k31(:,i), ...
                                          CL(:,i), k1(:,i), k2(:,i), ...
                                          Kmax(:,i), KC50(:,i)];
                end
            end
            
            % Cluster Computing
            
            if isempty(gcp)
                parpool;
            end
            
            tObs      = 0:24:336       ; % ore
            nTPoints  = length(tObs)   ; % numar de puncte
            
            % Simulare Monte Carlo pentru pacienti
            cfu          = nan(nTPoints,nPatients);
            log10CFU     = cell(1,nDoseGrps) ;
            
            for i = 1:nDoseGrps
                disp(['Simulating group ', num2str(i),'...'])
            
                %Alegere doze
                doseTable = getTable(doseRegimens(i));
            
                % Simulare
                simdata = simfunc(phi{i},[],doseTable,tObs);
            
                % Suma numarului de bacterii în crestere si repaus
                for j = 1:nPatients
                   cfu(:,j) = sum(simdata(j).Data,2);
                end
                % Salvare transformari in logaritmi pentru fiecare grupa de doza
                log10CFU{i} = log10(cfu);
            end
            
            % Save rezultate
            log10CFU_250bid = log10CFU{1} ;
            log10CFU_250tid = log10CFU{2} ;
            log10CFU_500bid = log10CFU{3} ;
            log10CFU_500tid = log10CFU{4} ;
            
            %Convertire complex to double
            
            log10CFU_250bid = real(log10CFU_250bid);
            log10CFU_250tid = real(log10CFU_250tid);
            log10CFU_500bid = real(log10CFU_500bid);
            log10CFU_500tid = real(log10CFU_500tid);
            
            %Terminare Cluster Computing
            delete(gcp('nocreate'));
            
            % Afisare evolutie in timp ale numarului de bacterii
            
            hax1(1) = subplot(2,2,1);
            plotCFUCount(tObs, log10CFU_250bid, 'a. Doza 250mg bid' )
            hax1(2) = subplot(2,2,2);
            plotCFUCount(tObs, log10CFU_250tid, 'b. Doza 250mg tid' )
            hax1(3) = subplot(2,2,3);
            plotCFUCount(tObs, log10CFU_500bid, 'c. Doza 500mg bid' )
            hax1(4) = subplot(2,2,4);
            plotCFUCount(tObs, log10CFU_500tid, 'd. Doza 500mg tid' )
            linkaxes(hax1);
            
            %Efectul functiei renale
            
            % Preallocate
            idCrCLGrp   = false(nPatients, nDoseGrps) ;
            
            % Line Style
            ls = {'bd:', 'b*:', 'rd:', 'r*:'} ;
            
            titleStr = {'CrCL < 30'           , ...
                        '30 <= CrCL < 50'     , ...
                        '50 <= CrCL < 70'     , ...
                        'CrCL > 70'            };
            
            f = figure;
            f.Color = 'w';
            
            for iCrCLGrp = 1:4 % Grupele de rata de creatinina
            
                hax2(iCrCLGrp) = subplot(2,2, iCrCLGrp) ;
                title( titleStr{iCrCLGrp}   ) ;
                ylabel('Prob(log10CFU < 0)' ) ;
                xlabel('Ore'       ) ;
            
            end
            
            
            set(hax2,   'XTick'        , 0:48:336      , ...
                        'XTickLabel'   , 0:48:336      , ...
                        'Ylim'         , [0 1]         , ...
                        'Xlim'         , [0 336]       , ...
                        'NextPlot'     , 'add'         , ...
                        'Box'          , 'on'          );
            
            % Plot rezultate pe grupe
            for iDoseGrp = 1:nDoseGrps
            
                % Extract indices for renal function
                idCrCLGrp(:, 1) = CrCL(:,iDoseGrp) < 30                                    ;
                idCrCLGrp(:, 2) = CrCL(:,iDoseGrp) >= 30 & CrCL(:,iDoseGrp) < 50           ;
                idCrCLGrp(:, 3) = CrCL(:,iDoseGrp) >= 50 & CrCL(:,iDoseGrp) < 70           ;
                idCrCLGrp(:, 4) = CrCL(:,iDoseGrp) >= 70                                   ;
            
                for iCrCLGrp = 1:4 % Creatinine Clearance Groups
            
                    % Calcul probabilitate
                    Pr = sum( ( log10CFU{iDoseGrp}(:, idCrCLGrp(:, iCrCLGrp)') < 0) , 2 )/sum(idCrCLGrp(:,iCrCLGrp)) ;
            
                    % Plot
                    plot(hax2(iCrCLGrp), tObs, Pr , ls{iDoseGrp}, 'MarkerSize', 7)
                end
            
            end
            
            legend(hax2(4), {'250 b.i.d.', '250 t.i.d.', '500 b.i.d.', '500 t.i.d.'} )
            legend location NorthWest
            legend boxoff
            
            linkaxes(hax2)
        
        
                app.CentralEditField.Enable ='on';
                app.CentralEditFieldLabel.Enable = 'on';
                app.KmaxEditField.Enable = 'on';
                app.KmaxEditFieldLabel.Enable = 'on';
                app.k1EditField.Enable = 'on';
                app.k1EditFieldLabel.Enable = 'on';
                app.k2EditField.Enable = 'on';
                app.k2EditFieldLabel.Enable = 'on';
                app.GreutateEditField.Enable = 'on';
                app.GreutateEditFieldLabel.Enable = 'on';
                app.VarstaEditField.Enable = 'on';
                app.VarstaEditFieldLabel.Enable = 'on';
                app.CreatininaSericaEditField.Enable = 'on';
                app.CreatininaSericaEditFieldLabel.Enable = 'on';
                app.DozajPacientButton.Enable = 'on';
                app.OrderDropDown.Enable = 'on';
                app.NrCompartimenteLabel.Enable = 'on';
                app.SexDropDown.Enable = 'on';
                app.SexLabel.Enable = 'on';
                app.TestaremetodededozareButton.Enable='on';
                app.SalvareDateButton.Enable ='on';
                app.IncarcareDateButton.Enable ='on';
                
                switch order
                    case '2'
                        app.k12EditField.Enable ='on';
                        app.k12EditFieldLabel.Enable ='on';
                        app.k21EditField.Enable ='on';
                        app.k21EditFieldLabel.Enable ='on';
                        app.k13EditField.Enable ='off';
                        app.k13EditFieldLabel.Enable ='off';
                        app.k31EditField.Enable ='off';
                        app.k31EditFieldLabel.Enable ='off';
                    case '3'
                        app.k12EditField.Enable ='on';
                        app.k12EditFieldLabel.Enable ='on';
                        app.k21EditField.Enable ='on';
                        app.k21EditFieldLabel.Enable ='on';
                        app.k13EditField.Enable ='on';
                        app.k13EditFieldLabel.Enable ='on';
                        app.k31EditField.Enable ='on';
                        app.k31EditFieldLabel.Enable ='on';
                end
        end

        % Button pushed function: SalvareDateButton
        function SalvareDateButtonPushed(app, event)
            k12 = app.k12EditField.Value;
            k21 = app.k21EditField.Value;
            k13 = app.k13EditField.Value;
            k31 = app.k31EditField.Value;
            k1 = app.k1EditField.Value;
            k2 = app.k2EditField.Value;
            Kmax = app.KmaxEditField.Value;
            Greutate = app.GreutateEditField.Value;
            Varsta = app.VarstaEditField.Value;
            Creatinina_Serica = app.CreatininaSericaEditField.Value;
            Central = app.CentralEditField.Value;
            Sex = string(app.SexDropDown.Value);
            order = app.OrderDropDown.Value;
            Nr_Compartimente = string(app.OrderDropDown.Value);

            switch order
                case '1'
                    k12 = 0;
                    k21 = 0;
                    k13 = 0;
                    k31 = 0;
                case '2'
                    k13 = 0;
                    k31 = 0;
            end
            Date_pacient = table(Sex, Varsta, Greutate, Creatinina_Serica, Nr_Compartimente, Central, k1, k2, Kmax, k12, k21, k13, k31);
            % Get the name of the file that the user wants to save.
            startingFolder = pwd; % Or "c:\mydata" or wherever you want.
            defaultFileName = fullfile(startingFolder, '*.xlsx');
            [baseFileName, folder] = uiputfile(defaultFileName, 'Specify a file');
            if baseFileName == 0
	        % User clicked the Cancel button.
	        return;
            end
            % Get base file name, so we can ignore whatever extension they may have typed in.
            [~, baseFileNameNoExt, ~] = fileparts(baseFileName);
            fullFileName = fullfile(folder, [baseFileNameNoExt, '.xlsx']);    % Force an extension of .xlsx.
            % Now write the table to an Excel workbook.
            writetable(Date_pacient, fullFileName);


        end

        % Button pushed function: IncarcareDateButton
        function IncarcareDateButtonPushed(app, event)
            [file,filepath] =uigetfile({'*.xlsx*'},'Select the Excel file');
            filename =fullfile(filepath,file);
            t= readtable(filename);
            app.VarstaEditField.Value = t{1,"Varsta"};
            app.GreutateEditField.Value = t{1,"Greutate"};
            app.CreatininaSericaEditField.Value = t{1,"Creatinina_Serica"};
            app.k1EditField.Value = t{1,"k1"};
            app.k2EditField.Value = t{1,"k2"};
            app.KmaxEditField.Value = t{1,"Kmax"};
            app.CentralEditField.Value = t{1,"Central"};
            app.k12EditField.Value = t{1,"k12"};
            app.k21EditField.Value = t{1,"k21"};
            app.k13EditField.Value = t{1,"k13"};
            app.k31EditField.Value = t{1,"k31"};
            app.SexDropDown.Value = t{1,"Sex"};
            app.OrderDropDown.Value = t{1,"Nr_Compartimente"};

            order = app.OrderDropDown.Value;

            switch order
                case '2'
                    app.k12EditField.Enable ='on';
                    app.k12EditFieldLabel.Enable ='on';
                    app.k21EditField.Enable ='on';
                    app.k21EditFieldLabel.Enable ='on';
                    app.k13EditField.Enable ='off';
                    app.k13EditFieldLabel.Enable ='off';
                    app.k31EditField.Enable ='off';
                    app.k31EditFieldLabel.Enable ='off';
                case '3'
                    app.k12EditField.Enable ='on';
                    app.k12EditFieldLabel.Enable ='on';
                    app.k21EditField.Enable ='on';
                    app.k21EditFieldLabel.Enable ='on';
                    app.k13EditField.Enable ='on';
                    app.k13EditFieldLabel.Enable ='on';
                    app.k31EditField.Enable ='on';
                    app.k31EditFieldLabel.Enable ='on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RezultateSimularePanel = uipanel(app.UIFigure);
            app.RezultateSimularePanel.Title = 'Rezultate Simulare';
            app.BC250mgbidUIAxes = uiaxes(app.RezultateSimularePanel);
            title(app.BC250mgbidUIAxes, 'Doza de 250 de miligrame de medicament administrata de doua ori pe zi')
            xlabel(app.BC250mgbidUIAxes, 'Ore')
            ylabel(app.BC250mgbidUIAxes, 'log10(CFU/mL)')
            zlabel(app.BC250mgbidUIAxes, 'Z')
            app.BC250mgbidUIAxes.XLim = [0 336];
            app.BC250mgbidUIAxes.YLim = [0 10];
            app.BC250mgtidUIAxes = uiaxes(app.RezultateSimularePanel);
            title(app.BC250mgtidUIAxes, 'Doza de 250 de miligrame de medicament administrata de trei ori pe zi')
            xlabel(app.BC250mgtidUIAxes, 'Ore')
            ylabel(app.BC250mgtidUIAxes, 'log10(CFU/mL)')
            zlabel(app.BC250mgtidUIAxes, 'Z')
            app.BC250mgtidUIAxes.XLim = [0 336];
            app.BC250mgtidUIAxes.YLim = [0 10];
            app.BC500mgbidUIAxes = uiaxes(app.RezultateSimularePanel);
            title(app.BC500mgbidUIAxes, 'Doza de 500 de miligrame de medicament administrata de doua ori pe zi')
            xlabel(app.BC500mgbidUIAxes, 'Ore')
            ylabel(app.BC500mgbidUIAxes, 'log10(CFU/mL)')
            zlabel(app.BC500mgbidUIAxes, 'Z')
            app.BC500mgbidUIAxes.XLim = [0 336];
            app.BC500mgbidUIAxes.YLim = [0 10];
            app.BC500mgtidUIAxes = uiaxes(app.RezultateSimularePanel);
            title(app.BC500mgtidUIAxes, 'Doza de 500 de miligrame de medicament administrata de trei ori pe zi')
            xlabel(app.BC500mgtidUIAxes, 'Ore')
            ylabel(app.BC500mgtidUIAxes, 'log10(CFU/mL)')
            zlabel(app.BC500mgtidUIAxes, 'Z')
            app.BC500mgtidUIAxes.XLim = [0 336];
            app.BC500mgtidUIAxes.YLim = [0 10];
            app.IncarcareSalvareDatePanel = uipanel(app.UIFigure);
            app.IncarcareSalvareDatePanel.Title = 'Incarcare/Salvare Date';
            app.SalvareDateButton = uibutton(app.IncarcareSalvareDatePanel, 'push');
            app.SalvareDateButton.ButtonPushedFcn = createCallbackFcn(app, @SalvareDateButtonPushed, true);
            app.SalvareDateButton.Text = 'Salvare Date';
            app.IncarcareDateButton = uibutton(app.IncarcareSalvareDatePanel, 'push');
            app.IncarcareDateButton.ButtonPushedFcn = createCallbackFcn(app, @IncarcareDateButtonPushed, true);
            app.IncarcareDateButton.Text = 'Incarcare Date';
            app.SimulareDozajPanel = uipanel(app.UIFigure);
            app.SimulareDozajPanel.Title = 'Simulare Dozaj';
            app.DozajPacientButton = uibutton(app.SimulareDozajPanel, 'push');
            app.DozajPacientButton.ButtonPushedFcn = createCallbackFcn(app, @DozajPacientButtonPushed, true);
            app.DozajPacientButton.Text = 'Dozaj Pacient';
            app.TestaremetodededozareButton = uibutton(app.SimulareDozajPanel, 'push');
            app.TestaremetodededozareButton.ButtonPushedFcn = createCallbackFcn(app, @TestaremetodededozareButtonPushed, true);
            app.TestaremetodededozareButton.Text = 'Testare metode de dozare';
            app.DatePacient = uipanel(app.UIFigure);
            app.DatePacient.Title = 'Date Pacient';
            app.GreutateEditFieldLabel = uilabel(app.DatePacient);
            app.GreutateEditFieldLabel.Text = 'Greutate';
            app.GreutateEditField = uieditfield(app.DatePacient, 'numeric');
            app.VarstaEditFieldLabel = uilabel(app.DatePacient);
            app.VarstaEditFieldLabel.Text = 'Varsta';
            app.VarstaEditField = uieditfield(app.DatePacient, 'numeric');
            app.CreatininaSericaEditFieldLabel = uilabel(app.DatePacient);
            app.CreatininaSericaEditFieldLabel.Text = {'Creatinina Serica'; ''};
            app.CreatininaSericaEditField = uieditfield(app.DatePacient, 'numeric');
            app.CrClEditFieldLabel = uilabel(app.DatePacient);
            app.CrClEditFieldLabel.Enable = 'off';
            app.CrClEditFieldLabel.Text = 'CrCl';
            app.CrClEditField = uieditfield(app.DatePacient, 'numeric');
            app.CrClEditField.Enable = 'off';
            app.SexLabel = uilabel(app.DatePacient);
            app.SexLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.DatePacient);
            app.SexDropDown.Items = {'Masculin', 'Feminin'};
            app.SexDropDown.Value = 'Masculin';
            app.ModelPDPanel = uipanel(app.UIFigure);
            app.ModelPDPanel.Title = 'Model PD';
            app.KmaxEditFieldLabel = uilabel(app.ModelPDPanel);
            app.KmaxEditFieldLabel.Text = 'Kmax';
            app.KmaxEditField = uieditfield(app.ModelPDPanel, 'numeric');
            app.k1EditFieldLabel = uilabel(app.ModelPDPanel);
            app.k1EditFieldLabel.Text = 'k1';
            app.k1EditField = uieditfield(app.ModelPDPanel, 'numeric');
            app.k2EditFieldLabel = uilabel(app.ModelPDPanel);
            app.k2EditFieldLabel.Text = 'k2';
            app.k2EditField = uieditfield(app.ModelPDPanel, 'numeric');
            app.ModelPKPanel = uipanel(app.UIFigure);
            app.ModelPKPanel.Title = 'Model PK';
            app.ParametriipentrutreicompartimentePanel = uipanel(app.ModelPKPanel);
            app.ParametriipentrutreicompartimentePanel.Title = 'Parametrii pentru trei compartimente';
            app.k13EditFieldLabel = uilabel(app.ParametriipentrutreicompartimentePanel);
            app.k13EditFieldLabel.Enable = 'off';
            app.k13EditFieldLabel.Text = 'k13';
            app.k13EditField = uieditfield(app.ParametriipentrutreicompartimentePanel, 'numeric');
            app.k13EditField.Enable = 'off';
            app.k31EditFieldLabel = uilabel(app.ParametriipentrutreicompartimentePanel);
            app.k31EditFieldLabel.Enable = 'off';
            app.k31EditFieldLabel.Text = 'k31';
            app.k31EditField = uieditfield(app.ParametriipentrutreicompartimentePanel, 'numeric');
            app.k31EditField.Enable = 'off';
            app.ParametriipentrudouacompartimentePanel = uipanel(app.ModelPKPanel);
            app.ParametriipentrudouacompartimentePanel.Title = 'Parametrii pentru doua compartimente';
            app.k12EditFieldLabel = uilabel(app.ParametriipentrudouacompartimentePanel);
            app.k12EditFieldLabel.Enable = 'off';
            app.k12EditFieldLabel.Text = 'k12';
            app.k12EditField = uieditfield(app.ParametriipentrudouacompartimentePanel, 'numeric');
            app.k12EditField.Enable = 'off';
            app.k21EditFieldLabel = uilabel(app.ParametriipentrudouacompartimentePanel);
            app.k21EditFieldLabel.Enable = 'off';
            app.k21EditFieldLabel.Text = 'k21';
            app.k21EditField = uieditfield(app.ParametriipentrudouacompartimentePanel, 'numeric');
            app.k21EditField.Enable = 'off';
            app.CentralEditFieldLabel = uilabel(app.ModelPKPanel);
            app.CentralEditFieldLabel.Text = {'Central'; ''};
            app.CentralEditField = uieditfield(app.ModelPKPanel, 'numeric');
            app.NrCompartimenteLabel = uilabel(app.ModelPKPanel);
            app.NrCompartimenteLabel.Text = 'Nr. Compartimente';
            app.OrderDropDown = uidropdown(app.ModelPKPanel);
            app.OrderDropDown.Items = {'1', '2', '3'};
            app.OrderDropDown.ValueChangedFcn = createCallbackFcn(app, @OrderDropDownValueChanged, true);
            app.OrderDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfata_Stabilire_Doza_Optima

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end